classdef RE_portfolio_investment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileNameField                 matlab.ui.control.EditField
        LoaddataButton                matlab.ui.control.Button
        PriceMWhDropDownLabel         matlab.ui.control.Label
        PriceDropDown                 matlab.ui.control.DropDown
        ProductionkWhkWDropDownLabel  matlab.ui.control.Label
        ProductionDropDown            matlab.ui.control.DropDown
        CapitalcostkWDropDownLabel    matlab.ui.control.Label
        CapitalcostDropDown           matlab.ui.control.DropDown
        LogArea                       matlab.ui.control.TextArea
        NameEditFieldLabel            matlab.ui.control.Label
        NameEditField                 matlab.ui.control.EditField
        StartsimulationButton         matlab.ui.control.Button
        FITsolarMWhLabel              matlab.ui.control.Label
        FITsolarDropDown              matlab.ui.control.DropDown
        FITwindMWhDropDownLabel       matlab.ui.control.Label
        FITwindDropDown               matlab.ui.control.DropDown
        FeedintariffFITDropDownLabel  matlab.ui.control.Label
        FeedintariffDropDown          matlab.ui.control.DropDown
        BudgetEditFieldLabel          matlab.ui.control.Label
        BudgetEditField               matlab.ui.control.NumericEditField
        PlotDropDown                  matlab.ui.control.DropDown
        PlotButton                    matlab.ui.control.Button
        UIAxes_a                      matlab.ui.control.UIAxes
        UIAxes_b                      matlab.ui.control.UIAxes
        UIAxes_c                      matlab.ui.control.UIAxes
        UIAxes_d                      matlab.ui.control.UIAxes
        XEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.EditField
        YEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.EditField
    end


    properties (Access = private)
        Data % all data from .mat
        Price % price data for a year
        Production % production of 8 power plants for a year
        Capital_cost
        Budget
        Whether_FIT % whether there is Feed-in tariff
        FIT_solar
        FIT_wind
        Result
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if exist('results.mat') 
                variables = who('-file','results.mat');
                app.PlotDropDown.Items = variables;
            end
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            dataname = fullfile(path, file);
            app.FileNameField.Value = dataname;
            app.Data = load(dataname);
            variables = who('-file',dataname);
            app.PriceDropDown.Items = variables;
            app.ProductionDropDown.Items = variables;
            app.CapitalcostDropDown.Items = variables;
            app.FITsolarDropDown.Items = variables;
            app.FITwindDropDown.Items = variables;
            msg = sprintf("Loaded data from '%s'.\n", file);
            app.LogArea.Value = cellstr(msg);
        end

        % Value changed function: PriceDropDown
        function PriceDropDownValueChanged(app, event)
            price_data = sprintf("app.Data.%s",app.PriceDropDown.Value);
            app.Price = eval(price_data);
            msg = sprintf("Price = %s (%s).",app.PriceDropDown.Value, mat2str(size(app.Price)));
            app.LogArea.Value(end+1) = {msg};
        end

        % Value changed function: CapitalcostDropDown
        function CapitalcostDropDownValueChanged(app, event)
            cap_cost_data = sprintf("app.Data.%s",app.CapitalcostDropDown.Value);
            app.Capital_cost = eval(cap_cost_data);
            msg = sprintf("Capital cost = %s (%s).",app.CapitalcostDropDown.Value, mat2str(size(app.Capital_cost)));
            app.LogArea.Value(end+1) = {msg};
        end

        % Value changed function: ProductionDropDown
        function ProductionDropDownValueChanged(app, event)
            production_data = sprintf("app.Data.%s", app.ProductionDropDown.Value);
            app.Production = eval(production_data);
            msg = sprintf("Production = %s (%s).",app.ProductionDropDown.Value, mat2str(size(app.Production)));
            app.LogArea.Value(end+1) = {msg};
        end

        % Button pushed function: StartsimulationButton
        function StartsimulationButtonPushed(app, event)
            app.StartsimulationButton.Enable = "off";
            % read user inputs
            %result_name = sprintf("result_%s", app.NameEditField.Value);
            price_data = sprintf("app.Data.%s",app.PriceDropDown.Value);
            app.Price = eval(price_data);
            cap_cost_data = sprintf("app.Data.%s",app.CapitalcostDropDown.Value);
            app.Capital_cost = eval(cap_cost_data);
            production_data = sprintf("app.Data.%s", app.ProductionDropDown.Value);
            app.Production = eval(production_data);
            app.Budget = app.BudgetEditField.Value;     
            app.Whether_FIT = strcmpi(app.FeedintariffDropDown.Value, "true");
            fit_solar_data = sprintf("app.Data.%s", app.FITsolarDropDown.Value);
            app.FIT_solar = eval(fit_solar_data);
            fit_wind_data = sprintf("app.Data.%s", app.FITwindDropDown.Value);
            app.FIT_wind = eval(fit_wind_data);
            
            result_struct = struct;
            
            production = app.Production;
            price = app.Price;
            budget = app.Budget;
            capital = app.Capital_cost;
            whether_fit = app.Whether_FIT;
            fit_wind = app.FIT_wind;
            fit_solar = app.FIT_solar;
            
            %% global parameters
            num = size(production,2);       % number of power plants
            
            %% ------------------------Without storage-------------------------
            app.LogArea.Value(end+1) = {newline};
            app.LogArea.Value(end+1) = {'Scenarios without storage ...'};
            %% 100% & 25% scenarios
            
            % AN EMPTY MATRIX are created to record
            % ROWS: scenarios
            % COLUMES:
            %   1: number of simulation; 
            %   2-10: weights (["Solar north","Solar east","Solar west","Solar south","Wind north","Wind east","Wind west","Wind south","storage"];)
            %   11: average monthly revenue [EUR]
            %   12: risk [EUR]
            
            record_sce = zeros (10,12);
            
            % Simulate 8 100% scenarios
            for n = 1:num
                w = zeros (1,num+1);        % 9 weights, including storage
                w(n) = 1;
                [revenue,risk]= RaR (w, budget, production,price,capital,whether_fit,fit_wind,fit_solar);
                record_sce(n,:) = [n,w,revenue,risk];
            end
            
            % Simulate 2 25% scenarios
            w = [0.25,0.25,0.25,0.25,0,0,0,0,0];            % only solar plants
            [revenue,risk]= RaR (w, budget, production,price,capital,whether_fit,fit_wind,fit_solar);
            record_sce(9,:) = [n,w,revenue,risk];
            
            w = [0,0,0,0,0.25,0.25,0.25,0.25,0];            % only wind plants
            [revenue,risk]= RaR (w, budget, production,price,capital,whether_fit,fit_wind,fit_solar);
            record_sce(10,:) = [n,w,revenue,risk];
            
            %% simulation: 4 locations * wind
            app.LogArea.Value(end+1) = {newline};
            app.LogArea.Value(end+1) = {'only wind calculating...'};
            
            sim = 1000; % times of simulation
            record_wind = zeros (sim,12);
            
            % simulation: only varies 5th-8th weight (of wind plants)
            for n = 1:sim
                rng(n);
                % starting from all zeros
                w = zeros (1,num+1); % 9, including storage
                % random order to assign weight
                order = datasample([5:8],4,'Replace',false);
                % 'rand' function: random number from uniform distribution [0,1]
                for i = order(1:(end-1))
                    w (i) = (1-sum(w))*rand;
                end
                % to ensure that weights sum up to 1
                w (order(end)) = (1-sum(w));
                [revenue,risk]= RaR (w, budget, production,price,capital,whether_fit,fit_wind,fit_solar);
                record_wind(n,:) = [n,w,revenue,risk];
            end
            % to combine with scarios above
            record_wind = [record_wind;record_sce(5:8,:);record_sce(10,:)];
            
            
            %% simulation: 4 locations * solar
            app.LogArea.Value(end+1) = {'only solar calculating...'};
            
            record_solar = zeros (sim,12);
            for n = 1:sim
                rng(n);
                w = zeros (1,num+1);
                order = datasample([1:4],4,'Replace',false);
                for i = order(1:(end-1))
                    w (i) = (1-sum(w))*rand;
                end
                w (order(end)) = (1-sum(w));
                [revenue,risk]= RaR (w, budget, production,price,capital,whether_fit,fit_wind,fit_solar);
                record_solar(n,:) = [n,w,revenue,risk];
            end
            record_solar = [record_solar;record_sce(1:4,:);record_sce(9,:)];
            
            
            %% simulation: NORTH * 2 tech
            app.LogArea.Value(end+1) = {'only north calculating...'};
            
            record_N = zeros (sim,12);
            for n = 1:sim
                rng(n);
                w = zeros (1,num+1);
                order = datasample([1,5],2,'Replace',false);
                for i = order(1:(end-1))
                    w (i) = (1-sum(w))*rand;
                end
                w (order(end)) = (1-sum(w));
                [revenue,risk]= RaR (w, budget, production,price,capital,whether_fit,fit_wind,fit_solar);
                record_N(n,:) = [n,w,revenue,risk];
            end
            record_N = [record_N;record_sce(1,:);record_sce(5,:)];
            
            
            %% simulation: EAST * 2 tech
            app.LogArea.Value(end+1) = {'only east calculating...'};
            
            sim = 500;
            record_E = zeros (sim,12);
            for n = 1:sim
                rng(n);
                w = zeros (1,num+1);
                order = datasample([2,6],2,'Replace',false);
                for i = order(1:(end-1))
                    w (i) = (1-sum(w))*rand;
                end
                w (order(end)) = (1-sum(w));
                [revenue,risk]= RaR (w, budget, production,price,capital,whether_fit,fit_wind,fit_solar);
                record_E(n,:) = [n,w,revenue,risk];
            end
            record_E = [record_E;record_sce(2,:);record_sce(6,:)];
            
            
            %% simulation: WEST * 2 tech
            app.LogArea.Value(end+1) = {'only west calculating...'};
            
            sim = 500;
            record_W = zeros (sim,12);
            for n = 1:sim
                rng(n);
                w = zeros (1,num+1);
                order = datasample([3,7],2,'Replace',false);
                for i = order(1:(end-1))
                    w (i) = (1-sum(w))*rand;
                end
                w (order(end)) = (1-sum(w));
                [revenue,risk]= RaR (w, budget, production,price,capital,whether_fit,fit_wind,fit_solar);
                record_W(n,:) = [n,w,revenue,risk];
            end
            record_W = [record_W;record_sce(3,:);record_sce(7,:)];
            
            
            %% simulation: SOUTH * 2 tech
            app.LogArea.Value(end+1) = {'only south calculating...'};
            
            sim = 500;
            record_S = zeros (sim,12);
            for n = 1:sim
                rng(n);
                w = zeros (1,num+1);
                order = datasample([4,8],2,'Replace',false);
                for i = order(1:(end-1))
                    w (i) = (1-sum(w))*rand;
                end
                w (order(end)) = (1-sum(w));
                [revenue,risk]= RaR (w, budget, production,price,capital,whether_fit,fit_wind,fit_solar);
                record_S(n,:) = [n,w,revenue,risk];
            end
            record_S = [record_S;record_sce(4,:);record_sce(8,:)];
            
            
            %% simulation: 4 locations * 2 tech
            app.LogArea.Value(end+1) = {'all simulations calculating...'};
            
            sim = 3000; % times of simulation
            record_new = zeros (sim,12);
            for n = 1:sim
                rng(n);
                w = zeros (1,num+1);
                order = datasample([1:num],num,'Replace',false);
                for i = order(1:(end-1))
                    w (i) = (1-sum(w))*rand;
                end
                w (order(end)) = (1-sum(w));
                [revenue,risk]= RaR (w, budget, production,price,capital,whether_fit,fit_wind,fit_solar);
                record_new(n,:) = [n,w,revenue,risk];
            end
            record_without_storage = [record_wind;record_solar;record_N;record_E;record_W;record_S;record_sce;record_new];
            app.LogArea.Value(end+1) = {'- intensifying points around the frontier...'};
            record_more = intensify(record_without_storage,budget, production,price,capital,false,whether_fit,fit_wind,fit_solar);
            record_without_storage = [record_without_storage;record_more];


            %% ------------------------With storage--------------------------
            app.LogArea.Value(end+1) = {newline};
            app.LogArea.Value(end+1) = {'Scenarios with storage ...'};
            
            %% 100% storage
            w = [0,0,0,0, 0,0,0,0, 1];
            [revenue,risk]= RaR (w, budget, production,price,capital,whether_fit,fit_wind,fit_solar);
            record_only_storage = [1,w,revenue,risk];
            
            %% simulation: 4 locations * 2 tech + storage
            app.LogArea.Value(end+1) = {'all simulations calculating...'};

            sim = 500; % times of simulation
            record_with_storage = zeros(sim,12);
            for n = 1:sim
                rng(n);
                w = zeros (1,num+1);
                order = datasample([1:num+1],num+1,'Replace',false);
                for i = order(1:(end-1))
                    w (i) = (1-sum(w))*rand;
                end
                w (order(end)) = (1-sum(w));
                [revenue,risk]= RaR (w, budget, production,price,capital,whether_fit,fit_wind,fit_solar);
                record_with_storage(n,:) = [n,w,revenue,risk];
            end
            record_with_storage = [record_with_storage;record_only_storage;record_without_storage];
            record_more = intensify(record_with_storage,budget, production,price,capital,true,whether_fit,fit_wind,fit_solar);
            app.LogArea.Value(end+1) = {'- intensifying points around the frontier...'};
            record_with_storage = [record_with_storage;record_more];
            
            %scatter (record_with_storage(:,12),record_with_storage(:,11),'kx')
            
            
            %% simulation: NORTH * 2 tech + storage
            app.LogArea.Value(end+1) = {'only north calculating...'};

            sim = 500;
            record_N_with = zeros (sim,12);
            for n = 1:sim
                rng(n);
                w = zeros (1,num+1);
                order = datasample([1,5,num+1],3,'Replace',false);
                for i = order(1:(end-1))
                    w (i) = (1-sum(w))*rand;
                end
                w (order(end)) = (1-sum(w));
                [revenue,risk]= RaR (w, budget, production,price,capital,whether_fit,fit_wind,fit_solar);
                record_N_with(n,:) = [n,w,revenue,risk];
            end
            record_N_with = [record_N_with;record_sce(1,:);record_sce(5,:)];
            
            %% simulation: EAST * 2 tech + storage
            app.LogArea.Value(end+1) = {'only east calculating...'};
            
            record_E_with = zeros (sim,12);
            for n = 1:sim
                rng(n);
                w = zeros (1,num+1);
                order = datasample([2,6,num+1],3,'Replace',false);
                for i = order(1:(end-1))
                    w (i) = (1-sum(w))*rand;
                end
                w (order(end)) = (1-sum(w));
                [revenue,risk]= RaR (w, budget, production,price,capital,whether_fit,fit_wind,fit_solar);
                record_E_with(n,:) = [n,w,revenue,risk];
            end
            record_E_with = [record_E_with;record_sce(2,:);record_sce(6,:)];

            
            %% simulation: WEST * 2 tech + storage
            app.LogArea.Value(end+1) = {'only west calculating...'};

            
            record_W_with = zeros (sim,12);
            for n = 1:sim
                rng(n);
                w = zeros (1,num+1);
                order = datasample([3,7,num+1],3,'Replace',false);
                for i = order(1:(end-1))
                    w (i) = (1-sum(w))*rand;
                end
                w (order(end)) = (1-sum(w));
                [revenue,risk]= RaR (w, budget, production,price,capital,whether_fit,fit_wind,fit_solar);
                record_W_with(n,:) = [n,w,revenue,risk];
            end
            record_W_with = [record_W_with;record_sce(3,:);record_sce(7,:)];

            
            %% simulation: SOUTH * 2 tech + storage
            app.LogArea.Value(end+1) = {'only south calculating...'};
            
            record_S_with = zeros (sim,12);
            for n = 1:sim
                rng(n);
                w = zeros (1,num+1);
                order = datasample([4,8,num+1],3,'Replace',false);
                for i = order(1:(end-1))
                    w (i) = (1-sum(w))*rand;
                end
                w (order(end)) = (1-sum(w));
                [revenue,risk]= RaR (w, budget, production,price,capital,whether_fit,fit_wind,fit_solar);
                record_S_with(n,:) = [n,w,revenue,risk];
            end
            record_S_with = [record_S_with;record_sce(4,:);record_sce(8,:)];

            
            %% ------------------------Save---------------------------------- 
            %% save
            
            result_struct.price = price;
            
            result_struct.record_sce = record_sce;
            result_struct.record_wind = record_wind;
            result_struct.record_solar = record_solar;
            
            result_struct.record_N = record_N;
            result_struct.record_E = record_E;
            result_struct.record_W = record_W;
            result_struct.record_S = record_S;
            result_struct.record_without_storage = record_without_storage;
            
            result_struct.record_only_storage = record_only_storage;
            result_struct.record_with_storage = record_with_storage;
            
            result_struct.record_N_with = record_N_with;
            result_struct.record_E_with = record_E_with;
            result_struct.record_W_with = record_W_with;
            result_struct.record_S_with = record_S_with;
            
            expression = sprintf("result_%s = result_struct;", app.NameEditField.Value);
            eval(expression)
            
            %%
            if exist('results.mat')
                save('results.mat','-regexp','^result', '-append');
            else
                save('results.mat','-regexp','^result');
            end
           
            msg = sprintf("result_%s is saved in results.mat.", app.NameEditField.Value);
            app.LogArea.Value(end+1) = {msg};
            
            app.StartsimulationButton.Enable = "on";
            
            variables = who('-file',"results.mat");
            app.PlotDropDown.Items = variables;
        end

        % Value changed function: FeedintariffDropDown
        function FeedintariffDropDownValueChanged(app, event)
            app.Whether_FIT = strcmpi(app.FeedintariffDropDown.Value, "true");
            if app.Whether_FIT
                app.FITsolarDropDown.Enable = "on";
                app.FITwindDropDown.Enable = "on";
            else
                app.FIT_solar = "false";
                app.FIT_wind = "false";
            end
        end

        % Value changed function: FITsolarDropDown
        function FITsolarDropDownValueChanged(app, event)
            fit_solar_data = sprintf("app.Data.%s", app.FITsolarDropDown.Value);
            app.FIT_solar = eval(fit_solar_data);
            msg = sprintf("FIT solar = %s (%s).",app.FITsolarDropDown.Value, mat2str(size(app.FIT_solar)));
            app.LogArea.Value(end+1) = {msg};
        end

        % Value changed function: FITwindDropDown
        function FITwindDropDownValueChanged(app, event)
            fit_wind_data = sprintf("app.Data.%s", app.FITwindDropDown.Value);
            app.FIT_wind = eval(fit_wind_data);
            msg = sprintf("FIT wind = %s (%s).",app.FITwindDropDown.Value, mat2str(size(app.FIT_wind)));
            app.LogArea.Value(end+1) = {msg};
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            data = load("results.mat");
            expression = sprintf("data.%s", app.PlotDropDown.Value);
            app.Result = eval(expression);
            which = app.Result;
            
            expression = sprintf("x_range = %s;", app.XEditField.Value);
            eval(expression);
            expression = sprintf("y_range = %s;", app.YEditField.Value);
            eval(expression);
            
            app.UIAxes_a.XLim = x_range;
            app.UIAxes_a.YLim = y_range;
            hold(app.UIAxes_a);
            mark={'ro','r+','r*','rs','bo','b+','b*','bs'};
            for i = 1:8
                scatter(app.UIAxes_a, which.record_sce(i,end),which.record_sce(i,end-1),mark{i})
            end
            scatter(app.UIAxes_a, which.record_only_storage(1,end),which.record_only_storage(1,end-1),'ms');
  
            app.UIAxes_b.XLim = x_range;
            app.UIAxes_b.YLim = y_range;
            hold(app.UIAxes_b);
            mark={'ro','r+','r*','rs','bo','b+','b*','bs'};
            for i = 1:8
                scatter(app.UIAxes_b, which.record_sce(i,end),which.record_sce(i,end-1),mark{i})
            end
            scatter(app.UIAxes_b, which.record_only_storage(1,end),which.record_only_storage(1,end-1),'ms');
            frontier(app.UIAxes_b, which.record_wind,'b','-.',true);
            frontier(app.UIAxes_b, which.record_solar,'r','-.',true);
            
            app.UIAxes_c.XLim = x_range;
            app.UIAxes_c.YLim = y_range;
            hold(app.UIAxes_c);
            mark={'ro','r+','r*','rs','bo','b+','b*','bs'};
            for i = 1:8
                scatter(app.UIAxes_c, which.record_sce(i,end),which.record_sce(i,end-1),mark{i})
            end
            scatter(app.UIAxes_c, which.record_only_storage(1,end),which.record_only_storage(1,end-1),'ms');
            frontier(app.UIAxes_c, which.record_wind,'b','-.',true);
            frontier(app.UIAxes_c, which.record_solar,'r','-.',true);            
            
            frontier(app.UIAxes_c, which.record_N,[0 0.5 0],'--',true);
            frontier(app.UIAxes_c, which.record_E,[0 0.75 0.25],'--',true);
            frontier(app.UIAxes_c, which.record_W,[0.5 0.7 0.2],'--',true);
            frontier(app.UIAxes_c, which.record_S,[0.75 0.75 0],'--',true);
            
            frontier(app.UIAxes_c, which.record_N_with,[0 0.5 0],'-',true);
            frontier(app.UIAxes_c, which.record_E_with,[0 0.75 0.25],'-',true);
            frontier(app.UIAxes_c, which.record_W_with,[0.5 0.7 0.2],'-',true);
            frontier(app.UIAxes_c, which.record_S_with,[0.75 0.75 0],'-',true);
            
            app.UIAxes_d.XLim = x_range;
            app.UIAxes_d.YLim = y_range;
            hold(app.UIAxes_d);
            mark={'ro','r+','r*','rs','bo','b+','b*','bs'};
            for i = 1:8
                scatter(app.UIAxes_d, which.record_sce(i,end),which.record_sce(i,end-1),mark{i})
            end
            scatter(app.UIAxes_d, which.record_only_storage(1,end),which.record_only_storage(1,end-1),'ms');
            frontier(app.UIAxes_d, which.record_wind,'b','-.',true);
            frontier(app.UIAxes_d, which.record_solar,'r','-.',true);            
            
            frontier(app.UIAxes_d, which.record_N,[0 0.5 0],'--',true);
            frontier(app.UIAxes_d, which.record_E,[0 0.75 0.25],'--',true);
            frontier(app.UIAxes_d, which.record_W,[0.5 0.7 0.2],'--',true);
            frontier(app.UIAxes_d, which.record_S,[0.75 0.75 0],'--',true);
            
            frontier(app.UIAxes_d, which.record_N_with,[0 0.5 0],'-',true);
            frontier(app.UIAxes_d, which.record_E_with,[0 0.75 0.25],'-',true);
            frontier(app.UIAxes_d, which.record_W_with,[0.5 0.7 0.2],'-',true);
            frontier(app.UIAxes_d, which.record_S_with,[0.75 0.75 0],'-',true);
            
            frontier(app.UIAxes_d, which.record_without_storage,'k','-.',true);
            frontier(app.UIAxes_d, which.record_with_storage,'k','-',true);
            
            legend(app.UIAxes_d,'PV north','PV east','PV west','PV south',...
            'Wind north','Wind east','Wind west','Wind south','Storage',...
            'Wind all locations','Solar all locations',...
            'PV & wind north','PV & wind east','PV & wind west','PV & wind south',...
            'VRE & storage north','VRE & storage east','VRE & storage west','VRE & storage south',...
            'VRE all locations', 'VRE & storage all locations',...
            'Location','southeast','Orientation','vertical');
            legend(app.UIAxes_d,'show');
        
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileNameField = uieditfield(app.UIFigure, 'text');
            app.LoaddataButton = uibutton(app.UIFigure, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.PriceMWhDropDownLabel = uilabel(app.UIFigure);
            app.PriceMWhDropDownLabel.Text = 'Price [€/MWh]';
            app.PriceDropDown = uidropdown(app.UIFigure);
            app.PriceDropDown.Items = {''};
            app.PriceDropDown.ValueChangedFcn = createCallbackFcn(app, @PriceDropDownValueChanged, true);
            app.PriceDropDown.Value = '';
            app.ProductionkWhkWDropDownLabel = uilabel(app.UIFigure);
            app.ProductionkWhkWDropDownLabel.Text = 'Production [kWh/kW]';
            app.ProductionDropDown = uidropdown(app.UIFigure);
            app.ProductionDropDown.Items = {''};
            app.ProductionDropDown.ValueChangedFcn = createCallbackFcn(app, @ProductionDropDownValueChanged, true);
            app.ProductionDropDown.Value = '';
            app.CapitalcostkWDropDownLabel = uilabel(app.UIFigure);
            app.CapitalcostkWDropDownLabel.Text = 'Capital cost  [€/kW]';
            app.CapitalcostDropDown = uidropdown(app.UIFigure);
            app.CapitalcostDropDown.Items = {''};
            app.CapitalcostDropDown.ValueChangedFcn = createCallbackFcn(app, @CapitalcostDropDownValueChanged, true);
            app.CapitalcostDropDown.Value = '';
            app.LogArea = uitextarea(app.UIFigure);
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.Value = 'original_price_production';
            app.StartsimulationButton = uibutton(app.UIFigure, 'push');
            app.StartsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulationButtonPushed, true);
            app.StartsimulationButton.Text = 'Start simulation';
            app.FITsolarMWhLabel = uilabel(app.UIFigure);
            app.FITsolarMWhLabel.Enable = 'off';
            app.FITsolarMWhLabel.Text = 'FIT solar [€/MWh]';
            app.FITsolarDropDown = uidropdown(app.UIFigure);
            app.FITsolarDropDown.Items = {''};
            app.FITsolarDropDown.ValueChangedFcn = createCallbackFcn(app, @FITsolarDropDownValueChanged, true);
            app.FITsolarDropDown.Enable = 'off';
            app.FITsolarDropDown.Value = '';
            app.FITwindMWhDropDownLabel = uilabel(app.UIFigure);
            app.FITwindMWhDropDownLabel.Enable = 'off';
            app.FITwindMWhDropDownLabel.Text = 'FIT wind [€/MWh]';
            app.FITwindDropDown = uidropdown(app.UIFigure);
            app.FITwindDropDown.Items = {''};
            app.FITwindDropDown.ValueChangedFcn = createCallbackFcn(app, @FITwindDropDownValueChanged, true);
            app.FITwindDropDown.Enable = 'off';
            app.FITwindDropDown.Value = '';
            app.FeedintariffFITDropDownLabel = uilabel(app.UIFigure);
            app.FeedintariffFITDropDownLabel.Text = 'Feed-in tariff (FIT)';
            app.FeedintariffDropDown = uidropdown(app.UIFigure);
            app.FeedintariffDropDown.Items = {'true', 'false'};
            app.FeedintariffDropDown.ValueChangedFcn = createCallbackFcn(app, @FeedintariffDropDownValueChanged, true);
            app.FeedintariffDropDown.Value = 'false';
            app.BudgetEditFieldLabel = uilabel(app.UIFigure);
            app.BudgetEditFieldLabel.Text = 'Budget [€]';
            app.BudgetEditField = uieditfield(app.UIFigure, 'numeric');
            app.BudgetEditField.Value = 10000000;
            app.PlotDropDown = uidropdown(app.UIFigure);
            app.PlotDropDown.Items = {''};
            app.PlotDropDown.Value = '';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UIAxes_a = uiaxes(app.UIFigure);
            title(app.UIAxes_a, '(a, individual plants)')
            ylabel(app.UIAxes_a, 'Monthly average revenue [k €]')
            app.UIAxes_b = uiaxes(app.UIFigure);
            title(app.UIAxes_b, '(b, geographical diversification)')
            app.UIAxes_c = uiaxes(app.UIFigure);
            title(app.UIAxes_c, '(c, technological diversification)')
            xlabel(app.UIAxes_c, 'Monthly CVaR [k €]')
            ylabel(app.UIAxes_c, 'Monthly average revenue [k €]')
            app.UIAxes_d = uiaxes(app.UIFigure);
            title(app.UIAxes_d, '(d, full diversificatin)')
            xlabel(app.UIAxes_d, 'Monthly CVaR [k €]')
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'text');
            app.XEditField.Value = '[0, 25]';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'text');
            app.YEditField.Value = '[0, 40]';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RE_portfolio_investment

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end